classdef robot_team < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        JacobianButton             matlab.ui.control.Button
        DHParameterButton          matlab.ui.control.Button
        TorqueButton               matlab.ui.control.Button
        VelocityButton             matlab.ui.control.Button
        PointButton                matlab.ui.control.Button
        TSlider                    matlab.ui.control.Slider
        Label_2                    matlab.ui.control.Label
        dtheta_3EditField          matlab.ui.control.NumericEditField
        dtheta_3EditFieldLabel     matlab.ui.control.Label
        dtheta_2EditField          matlab.ui.control.NumericEditField
        dtheta_2EditFieldLabel     matlab.ui.control.Label
        dtheta_1EditField          matlab.ui.control.NumericEditField
        dtheta_1EditFieldLabel     matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        SphereButton               matlab.ui.control.Button
        point_pathButton           matlab.ui.control.Button
        show_pathButton            matlab.ui.control.Button
        robot_armButton            matlab.ui.control.Button
        link1_thetaEditField       matlab.ui.control.NumericEditField
        link1_thetaEditFieldLabel  matlab.ui.control.Label
        link3_alphaEditField       matlab.ui.control.NumericEditField
        link3_alphaEditFieldLabel  matlab.ui.control.Label
        link3_dEditField           matlab.ui.control.NumericEditField
        link3_dEditFieldLabel      matlab.ui.control.Label
        link3_thetaEditField       matlab.ui.control.NumericEditField
        link3_thetaEditFieldLabel  matlab.ui.control.Label
        link3_aEditField           matlab.ui.control.NumericEditField
        link3_aEditFieldLabel      matlab.ui.control.Label
        link3_ColorDropDown        matlab.ui.control.DropDown
        link3_ColorDropDownLabel   matlab.ui.control.Label
        link2_alphaEditField       matlab.ui.control.NumericEditField
        link2_alphaEditFieldLabel  matlab.ui.control.Label
        link2_dEditField           matlab.ui.control.NumericEditField
        link2_dEditFieldLabel      matlab.ui.control.Label
        link2_thetaEditField       matlab.ui.control.NumericEditField
        link2_thetaEditFieldLabel  matlab.ui.control.Label
        link2_aEditField           matlab.ui.control.NumericEditField
        link2_aEditFieldLabel      matlab.ui.control.Label
        link2_ColorDropDown        matlab.ui.control.DropDown
        link2_ColorDropDownLabel   matlab.ui.control.Label
        link1_alphaEditField       matlab.ui.control.NumericEditField
        link1_alphaEditFieldLabel  matlab.ui.control.Label
        link1_dEditField           matlab.ui.control.NumericEditField
        link1_dEditFieldLabel      matlab.ui.control.Label
        link1_aEditField           matlab.ui.control.NumericEditField
        link1_aEditFieldLabel      matlab.ui.control.Label
        link1_ColorDropDown        matlab.ui.control.DropDown
        link1_ColorDropDownLabel   matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: link1_dEditField
        function link1_dEditFieldValueChanged(app, event)
            d_1 = app.link1_dEditField.Value;
        end

        % Value changed function: link1_aEditField
        function link1_aEditFieldValueChanged(app, event)
            a_1 = app.link1_aEditField.Value;
        end

        % Value changed function: link1_alphaEditField
        function link1_alphaEditFieldValueChanged(app, event)
            alpha_1 = app.link1_alphaEditField.Value;
        end

        % Value changed function: link1_thetaEditField
        function link1_thetaEditFieldValueChanged(app, event)
            theta_1 = app.link1_thetaEditField.Value;
        end

        % Value changed function: link2_thetaEditField
        function link2_thetaEditFieldValueChanged(app, event)
            theta_2 = app.link2_thetaEditField.Value;
        end

        % Value changed function: link2_dEditField
        function link2_dEditFieldValueChanged(app, event)
            d_2 = app.link2_dEditField.Value;
        end

        % Value changed function: link2_aEditField
        function link2_aEditFieldValueChanged(app, event)
            a_2= app.link2_aEditField.Value;
        end

        % Value changed function: link2_alphaEditField
        function link2_alphaEditFieldValueChanged(app, event)
            alpha_2 = app.link2_alphaEditField.Value;
        end

        % Value changed function: link3_thetaEditField
        function link3_thetaEditFieldValueChanged(app, event)
            theta_3 = app.link3_thetaEditField.Value;
        end

        % Value changed function: link3_dEditField
        function link3_dEditFieldValueChanged(app, event)
            d_3 = app.link3_dEditField.Value;
        end

        % Value changed function: link3_aEditField
        function link3_aEditFieldValueChanged(app, event)
            a_3 = app.link3_aEditField.Value;
        end

        % Value changed function: link3_alphaEditField
        function link3_alphaEditFieldValueChanged(app, event)
            
            alpha_3 = app.link3_alphaEditField.Value;
        end

        % Value changed function: link1_ColorDropDown
        function link1_ColorDropDownValueChanged(app, event)
            color_1 = app.link1_ColorDropDown.Value;
            if color_1 == "Red"
                Color_1=[1 0 0];
            elseif color_1 == "Green"
                Color_1=[0 1 0];
            else
                Color_1=[0 0 1];
            end
        end

        % Value changed function: link2_ColorDropDown
        function link2_ColorDropDownValueChanged(app, event)
            color_2 = app.link2_ColorDropDown.Value;
            if color_2 == "Red"
                Color_2=[1 0 0];
            elseif color_2 == "Green"
                Color_2=[0 1 0];
            else
                Color_2=[0 0 1];
            end
        end

        % Value changed function: link3_ColorDropDown
        function link3_ColorDropDownValueChanged(app, event)
            color_3 = app.link3_ColorDropDown.Value;
            if color_3 == "Red"
                Color_3=[1 0 0];
            elseif color_3 == "Green"
                Color_3=[0 1 0];
            else
                Color_3=[0 0 1];
            end
        end

        % Button pushed function: robot_armButton
        function robot_armButtonPushed(app, event)
            Dhs=0; %%local variable
            
                theta_1 = deg2rad(app.link1_thetaEditField.Value);
                d_1 = app.link1_dEditField.Value;
                alpha_1 = deg2rad(app.link1_alphaEditField.Value);
                a_1 = app.link1_aEditField.Value;

                theta_2 = deg2rad(app.link2_thetaEditField.Value);
                d_2 = app.link2_dEditField.Value;
                alpha_2 = deg2rad(app.link2_alphaEditField.Value);
                a_2 = app.link2_aEditField.Value;

                theta_3 = deg2rad(app.link3_thetaEditField.Value);
                d_3 = app.link3_dEditField.Value;
                alpha_3 = deg2rad(app.link3_alphaEditField.Value);
                a_3 = app.link3_aEditField.Value;

                Dhs=[theta_1 d_1 alpha_1 a_1;
                    theta_2 d_2 alpha_2 a_2;
                    theta_3 d_3 alpha_3 a_3];
            
            [Tc,Tl] = genTm(Dhs);
            drawCord(Tc);
            drawLink(Tc);
            Tc(1,4,3)
            Tc(2,4,3)
            Tc(3,4,3)
            function drawLink(A)
                hold(app.UIAxes,"off");
                [l,c,w] = size(A);
                Color_1=app.link1_ColorDropDown.Value;
                Color_2=app.link2_ColorDropDown.Value;
                Color_3=app.link3_ColorDropDown.Value;
                if strcmp('Red',Color_1)
                    link1_C=[1 0 0];
                elseif strcmp('Blue',Color_1)
                    link1_C=[0 0 1];
                else
                    link1_C=[0 1 0];
                end
                if strcmp('Red',Color_2)
                    link2_C=[1 0 0];
                elseif strcmp('Blue',Color_2)
                    link2_C=[0 0 1];
                else
                    link2_C=[0 1 0];
                end
                if strcmp('Red',Color_3)
                    link3_C=[1 0 0];
                elseif strcmp('Blue',Color_3)
                    link3_C=[0 0 1];
                else
                    link3_C=[0 1 0];
                end
                Color=[link1_C;link2_C;link3_C];
                for i=1:1:w
                    if (i==1)
                        x=A(1,4,i);
                        y=A(2,4,i);
                        z=A(3,4,i);
                        drawEdge3d(app.UIAxes,[0 0 0 x y z],'Color',link1_C(i,:),'LineWidth',5);
                    else
                        x0=A(1,4,i-1);
                        y0=A(2,4,i-1);
                        z0=A(3,4,i-1);
                        x1=A(1,4,i);
                        y1=A(2,4,i);
                        z1=A(3,4,i);
                        drawEdge3d(app.UIAxes,[x0 y0 z0 x1 y1 z1],'Color',Color(i,:),'LineWidth',5);
                    end
                end
            end
            function drawCord(A)
                [l c w]=size(A);
                drawVector3d(app.UIAxes,[0,0,0],[5,0,0],'LineWidth',1,'Color',[0,0,0]);
                hold(app.UIAxes, 'on' );
                drawVector3d(app.UIAxes,[0,0,0],[0,5,0],'LineWidth',1,'Color',[0,0,0]);
                drawVector3d(app.UIAxes,[0,0,0],[0,0,5],'LineWidth',1,'Color',[0,0,0]);
                for i = 1:1:w
                    A_1 = A(:,:,i);
                    V_x = createVector([0,0,0],[A_1(1,1),A_1(2,1),A_1(3,1)]);
                    V_y = createVector([0,0,0],[A_1(1,2),A_1(2,2),A_1(3,2)]);
                    V_z = createVector([0,0,0],[A_1(1,3),A_1(2,3),A_1(3,3)]);
                    drawVector3d(app.UIAxes,[A_1(1,4),A_1(2,4),A_1(3,4)],V_x,'LineWidth',1.5,'Color',[1,0,0]);
                    drawVector3d(app.UIAxes,[A_1(1,4),A_1(2,4),A_1(3,4)],V_y,'LineWidth',1.5,'Color',[1,0,0]);
                    drawVector3d(app.UIAxes,[A_1(1,4),A_1(2,4),A_1(3,4)],V_z,'LineWidth',1.5,'Color',[1,0,0]);
                end
            end
            function T=DH(Dhs)
                th = Dhs(1);
                d = Dhs(2);
                alp = Dhs(3);
                a = Dhs(4);
                T=[ cos(th),  -sin(th)*cos(alp),  sin(th)*sin(alp), a*cos(th);...
                    sin(th),  cos(th)*cos(alp),   -cos(th)*sin(alp), a*sin(th);...
                    0,         sin(alp),            cos(alp),         d;...
                    0,            0,                   0,             1];
            end
            function [Tc,Tl] = genTm(Dhs)
                [l c] = size(Dhs);
                for i = 1:1:l
                    if (i==1)
                        Dhs(i,:);
                        Tl(:,:,i)=DH(Dhs(i,:));
                        Tc(:,:,i)=eye(4)*DH(Dhs(i,:));
                    else
                        Tl(:,:,i)=DH(Dhs(i,:));
                        Tc(:,:,i)=Tc(:,:,i-1)*Tl(:,:,i);
                    end
                end
            end

        end

        % Button pushed function: show_pathButton
        function show_pathButtonPushed(app, event)
            %Dhs=0; %%local variable
                dth_1=deg2rad(app.dtheta_1EditField.Value);
                dth_2=deg2rad(app.dtheta_2EditField.Value);
                dth_3=deg2rad(app.dtheta_3EditField.Value);
                dth=[dth_1; dth_2; dth_3];
                theta_1 = deg2rad(app.link1_thetaEditField.Value);
                d_1 = app.link1_dEditField.Value;
                alpha_1 = deg2rad(app.link1_alphaEditField.Value);
                a_1 = app.link1_aEditField.Value;

                theta_2 = deg2rad(app.link2_thetaEditField.Value);
                d_2 = app.link2_dEditField.Value;
                alpha_2 = deg2rad(app.link2_alphaEditField.Value);
                a_2 = app.link2_aEditField.Value;

                theta_3 = deg2rad(app.link3_thetaEditField.Value);
                d_3 = app.link3_dEditField.Value;
                alpha_3 = deg2rad(app.link3_alphaEditField.Value);
                a_3 = app.link3_aEditField.Value;
                    
                dt=0.01;
                t=app.TSlider.Value;
                L=t/dt;
                Dhs=[theta_1 d_1 alpha_1 a_1;
                    theta_2 d_2 alpha_2 a_2;
                    theta_3 d_3 alpha_3 a_3];
                for k=1:L
                    J = [-sin(theta_1)*(a_2*cos(theta_2)+a_3*cos(theta_2+theta_3)), -cos(theta_1)*(a_2*sin(theta_2)+a_3*sin(theta_2+theta_3)), -a_3*cos(theta_1)*sin(theta_2+theta_3);
                        cos(theta_1)*(a_2*cos(theta_2)+a_3*cos(theta_2+theta_3)), -sin(theta_1)*(a_2*sin(theta_2)+a_3*sin(theta_2+theta_3)), -a_3*sin(theta_1)*sin(theta_2+theta_3);
                        0                                     , a_2*cos(theta_2)+a_3*cos(theta_2+theta_3)            ,  a_3*cos(theta_2+theta_3)];
                    dp = J*dth;
                    Dhs=[theta_1, d_1 , alpha_1, a_1; theta_2, d_2, alpha_2, a_2; theta_3, d_3, alpha_3, a_3];
                    [Tc,Tl] = genTm(Dhs);
                    drawCord(Tc);
                    drawLink(Tc);

                    Px=Tc(1,4,3); Py=Tc(2,4,3); Pz = Tc(3,4,3); %끝점 좌표
                    dPx=dp(1); dPy=dp(2); dPz=dp(3); %해당 위치에서의 속도 벡터
                    drawVector3d(app.UIAxes,[Px Py Pz],[100*dPx*dt 0 0],'linewidth',2, 'color', [1 1 0]);
                    drawVector3d(app.UIAxes,[Px Py Pz],[0 100*dPy*dt 0],'linewidth',2, 'color', [1 1 0]);
                    drawVector3d(app.UIAxes,[Px Py Pz],[0 0 100*dPz*dt],'linewidth',2, 'color', [1 1 0]);
                    theta_1 = theta_1+dth_1*dt; theta_2 = theta_2+dth_2*dt; theta_3 = theta_3+dth_3*dt;
                    pause(0.0001);
                    hold(app.UIAxes,'off');
                end

            function drawLink(A)
                [l,c,w] = size(A);
                Color_1=app.link1_ColorDropDown.Value;
                Color_2=app.link2_ColorDropDown.Value;
                Color_3=app.link3_ColorDropDown.Value;

                if strcmp('Red',Color_1)
                    link1_C=[1 0 0];
                elseif strcmp('Blue',Color_1)
                    link1_C=[0 0 1];
                else
                    link1_C=[0 1 0];
                end
                if strcmp('Red',Color_2)
                    link2_C=[1 0 0];
                elseif strcmp('Blue',Color_2)
                    link2_C=[0 0 1];
                else
                    link2_C=[0 1 0];
                end
                if strcmp('Red',Color_3)
                    link3_C=[1 0 0];
                elseif strcmp('Blue',Color_3)
                    link3_C=[0 0 1];
                else
                    link3_C=[0 1 0];
                end

                Color=[link1_C;link2_C;link3_C];
                for i=1:1:w
                    if (i==1)
                        x=A(1,4,i);
                        y=A(2,4,i);
                        z=A(3,4,i);
                        drawEdge3d(app.UIAxes,[0 0 0 x y z],'Color',Color(i,:),'LineWidth',5);
                    else
                        x0=A(1,4,i-1);
                        y0=A(2,4,i-1);
                        z0=A(3,4,i-1);
                        x1=A(1,4,i);
                        y1=A(2,4,i);
                        z1=A(3,4,i);
                        drawEdge3d(app.UIAxes,[x0 y0 z0 x1 y1 z1],'Color',Color(i,:),'LineWidth',5);
                    end
                end
            end
            function drawCord(A)
                [l c w]=size(A);
                drawVector3d(app.UIAxes,[0,0,0],[5,0,0],'LineWidth',1,'Color',[0,0,0]);
                hold(app.UIAxes,'on');
                drawVector3d(app.UIAxes,[0,0,0],[0,5,0],'LineWidth',1,'Color',[0,0,0]);
                drawVector3d(app.UIAxes,[0,0,0],[0,0,5],'LineWidth',1,'Color',[0,0,0]);
                for i = 1:1:w
                    A_1 = A(:,:,i);
                    V_x = createVector([0,0,0],[A_1(1,1),A_1(2,1),A_1(3,1)]);
                    V_y = createVector([0,0,0],[A_1(1,2),A_1(2,2),A_1(3,2)]);
                    V_z = createVector([0,0,0],[A_1(1,3),A_1(2,3),A_1(3,3)]);
                    drawVector3d(app.UIAxes,[A_1(1,4),A_1(2,4),A_1(3,4)],V_x,'LineWidth',1.5,'Color',[1,0,0]);
                    drawVector3d(app.UIAxes,[A_1(1,4),A_1(2,4),A_1(3,4)],V_y,'LineWidth',1.5,'Color',[1,0,0]);
                    drawVector3d(app.UIAxes,[A_1(1,4),A_1(2,4),A_1(3,4)],V_z,'LineWidth',1.5,'Color',[1,0,0]);
                end
            end
            function T=DH(Dhs)
                th = Dhs(1);
                d = Dhs(2);
                alp = Dhs(3);
                a = Dhs(4);
                T=[ cos(th),  -sin(th)*cos(alp),  sin(th)*sin(alp), a*cos(th);...
                    sin(th),  cos(th)*cos(alp),   -cos(th)*sin(alp), a*sin(th);...
                    0,         sin(alp),            cos(alp),         d;...
                    0,            0,                   0,             1];
            end
            function [Tc,Tl] = genTm(Dhs)
                [l c] = size(Dhs);
                for i = 1:1:l
                    if (i==1)
                        Dhs(i,:)
                        Tl(:,:,i)=DH(Dhs(i,:));
                        Tc(:,:,i)=eye(4)*DH(Dhs(i,:));
                    else
                        Tl(:,:,i)=DH(Dhs(i,:));
                        Tc(:,:,i)=Tc(:,:,i-1)*Tl(:,:,i);
                    end
                end
            end
        end

        % Button pushed function: point_pathButton
        function point_pathButtonPushed(app, event)
            Dhs=0; %%local variable
            Color_1=app.link1_ColorDropDown.Value;
            Color_2=app.link2_ColorDropDown.Value;
            Color_3=app.link3_ColorDropDown.Value;
            if strcmp('Red',Color_1)
                link1_C=[1 0 0];
            elseif strcmp('Blue',Color_1)
                link1_C=[0 0 1];
            else
                link1_C=[0 1 0];
            end
            if strcmp('Red',Color_2)
                link2_C=[1 0 0];
            elseif strcmp('Blue',Color_2)
                link2_C=[0 0 1];
            else
                link2_C=[0 1 0];
            end
            if strcmp('Red',Color_3)
                link3_C=[1 0 0];
            elseif strcmp('Blue',Color_3)
                link3_C=[0 0 1];
            else
                link3_C=[0 1 0];
            end
            Color=[link1_C;link2_C;link3_C];
            plot(app.UIAxes,0,0);
            drawVector3d(app.UIAxes,[0,0,0],[5,0,0],'LineWidth',1,'Color',[0,0,0]);
            hold(app.UIAxes,'on');
            drawVector3d(app.UIAxes,[0,0,0],[0,5,0],'LineWidth',1,'Color',[0,0,0]);
            drawVector3d(app.UIAxes,[0,0,0],[0,0,5],'LineWidth',1,'Color',[0,0,0]);
            dth_1=deg2rad(app.dtheta_1EditField.Value);
            dth_2=deg2rad(app.dtheta_2EditField.Value);
            dth_3=deg2rad(app.dtheta_3EditField.Value);
            dth=[dth_1; dth_2; dth_3];
            theta_1 = deg2rad(app.link1_thetaEditField.Value);
            d_1 = app.link1_dEditField.Value;
            alpha_1 = deg2rad(app.link1_alphaEditField.Value);
            a_1 = app.link1_aEditField.Value;
            theta_2 = deg2rad(app.link2_thetaEditField.Value);
            d_2 = app.link2_dEditField.Value;
            alpha_2 = deg2rad(app.link2_alphaEditField.Value);
            a_2 = app.link2_aEditField.Value;
            theta_3 = deg2rad(app.link3_thetaEditField.Value);
            d_3 = app.link3_dEditField.Value;
            alpha_3 = deg2rad(app.link3_alphaEditField.Value);
            a_3 = app.link3_aEditField.Value;
            hold(app.UIAxes,'on');
            dt=0.01;
            t=app.TSlider.Value;
            L=t/dt;
            for j=1:L
                theta_1 = theta_1 + dth_1*dt;
                theta_2 = theta_2 + dth_2*dt;
                theta_3 = theta_3 + dth_3*dt;
                Dhs=[theta_1 d_1 alpha_1 a_1;
                    theta_2 d_2 alpha_2 a_2;
                    theta_3 d_3 alpha_3 a_3];
                [Tc,Tl] = genTm(Dhs);
                for k=1:1:3
                    drawPoint3d(app.UIAxes,Tc(1,4,k),Tc(2,4,k),Tc(3,4,k),'Marker','*','color',Color(k,:),'MarkerSize',7);
                    pause(0.0001);
                end
            end

            function T=DH(Dhs)
                th = Dhs(1);
                d = Dhs(2);
                alp = Dhs(3);
                a = Dhs(4);
                T=[ cos(th),  -sin(th)*cos(alp),  sin(th)*sin(alp), a*cos(th);...
                    sin(th),  cos(th)*cos(alp),   -cos(th)*sin(alp), a*sin(th);...
                    0,         sin(alp),            cos(alp),         d;...
                    0,            0,                   0,             1];
            end
            function [Tc,Tl] = genTm(Dhs)
                [l c] = size(Dhs);
                for i = 1:1:l
                    if (i==1)
                        Dhs(i,:)
                        Tl(:,:,i)=DH(Dhs(i,:));
                        Tc(:,:,i)=eye(4)*DH(Dhs(i,:));
                    else
                        Tl(:,:,i)=DH(Dhs(i,:));
                        Tc(:,:,i)=Tc(:,:,i-1)*Tl(:,:,i);
                    end
                end
            end
        end

        % Callback function
        function ResetButtonPushed(app, event)
            plot(app.UIAxes,0,0);
            hold(app.UIAxes,'off');
            drawEdge3d(app.UIAxes,[0,0,0],[0,0,app.link1_dEditField.Value]...
                ,'LineWidth',5,'Color',app.link1_ColorDropDown.Value)
            drawEdge3d(app.UIAxes,[0,0,app.link1_dEditField.Value],...
                [0,0,app.link1_dEditField.Value+app.link2_dEditField.Value],...
                'LineWidth',5,'Color',app.link2_ColorDropDown.Value)
            drawEdge3d(app.UIAxes,[0,0,app.link1_dEditField.Value+app.link2_dEditField.Value],...
                [0,0,app.link1_dEditField.Value+app.link2_dEditField.Value+app.link3_dEditField.Value],...
                'LineWidth',5,'Color',app.link3_ColorDropDown.Value)
        end

        % Button pushed function: SphereButton
        function SphereButtonPushed(app, event)
            hold(app.UIAxes,'off');
            hold(app.UIAxes,"on");
            [X,Y,Z]=sphere;
            if app.DropDown.Value=='1번 궤도'
                r=1.5;
                X2=X*r;
                Y2=Y*r-3;
                Z2=Z*r+2;
                surf(app.UIAxes,X2,Y2,Z2);
                theta_1 = deg2rad(app.link1_thetaEditField.Value);
                theta_2 = deg2rad(app.link2_thetaEditField.Value);
                theta_3 = deg2rad(app.link3_thetaEditField.Value);
                d_1 = app.link1_dEditField.Value;
                d_2 = app.link2_dEditField.Value;
                d_3 = app.link3_dEditField.Value;
                alpha_1 = deg2rad(app.link1_alphaEditField.Value);
                alpha_2 = deg2rad(app.link2_alphaEditField.Value);
                alpha_3 = deg2rad(app.link3_alphaEditField.Value);
                a_1 = app.link1_aEditField.Value;
                a_2 = app.link2_aEditField.Value;
                a_3 = app.link3_aEditField.Value;
                th12 = theta_1+theta_2; th23 = theta_2+theta_3; th13 = theta_1+theta_2+theta_3;
                dt=0.1;
                t = [0+dt:dt:3];
                L = length(t);
                dy=33/103;
                YY = [-15+dy:dy:-5];
                Lz = length(YY);
                z = -12/5*(-5)-6;
                zz = 12/5*(-10)+26;
                z0 = z;
                z1 = zz;
                zp = zeros(1,(Lz-1)/2); % 저장용도
                for i=1:(Lz-1)/2
                    z0(i)= zp(i) + -12/5*(-5-dy*i)-6;
                    z1(i)= zp(i) + 12/5*(-10-dy*i)+26;
                end
                for i=2:(Lz-1)/2
                    dzt1(i) = (z0(i)-z0(i-1))/dt;
                    dzt2(i) = (z1(i)-z1(i-1))/dt;
                end
                dotpz = zeros(1,Lz-1);
                dotpz(1:(Lz-1)/2) = dzt1;
                dotpz((Lz-1)/2+1:Lz-1) = dzt2;
                for i=2:Lz-1
                    dotpy(i) = (YY(i)-YY(i-1))/dt;
                end
                dotpx = zeros(1,Lz-1);
                for j=2:L
                    J = [-sin(theta_1(j-1))*(a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))), -cos(theta_1(j-1))*(a_2*sin(theta_2(j-1))+a_3*sin(th23(j-1))), -a_3*cos(theta_1(j-1))*sin(th23(j-1));
                        cos(theta_1(j-1))*(a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))), -sin(theta_1(j-1))*(a_2*sin(theta_2(j-1))+a_3*sin(th23(j-1))), -a_3*sin(theta_1(j-1))*sin(th23(j-1));
                        0                                  , a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))            , a_3*cos(th23(j-1))];
                    dth(j,:) = inv(J)*[dotpx(j); dotpy(j); dotpz(j)];
                    dth1(j,1) = dth(j,1);
                    dth2(j,1) = dth(j,2);
                    dth3(j,1) = dth(j,3);
                    theta_1(j) = theta_1(j-1)+dth1(j,1)*dt;
                    theta_2(j) = theta_2(j-1)+dth2(j,1)*dt;
                    theta_3(j) = theta_3(j-1)+dth3(j,1)*dt;
                    theta(j,:) = [theta_1(j), theta_2(j), theta_3(j)];
                    th23(j) = theta_2(j)+theta_3(j);
                    Dhs=[theta_1(j), d_1, alpha_1, a_1;
                        theta_2(j), d_2, alpha_2, a_2;
                        theta_3(j), d_3, alpha_3, a_3];

                    [Tc,Tl] = genTm(Dhs);
                    surf(app.UIAxes,X2,Y2,Z2);
                    hold(app.UIAxes,"on");
                    drawCord(Tc);
                    drawLink(Tc);
                    pause(0.5);
                    hold(app.UIAxes,"off");

                end
            elseif app.DropDown.Value=='2번 궤도'
                r=1;
                X2=X*r;
                Y2=Y*r-2;
                Z2=Z*r+2;
                surf(app.UIAxes,X2,Y2,Z2);

                % 초기값
                theta_1 = deg2rad(-90); theta_2 = 0; theta_3 = deg2rad(-90);
                d_1 = 5; d_2 =0; d_3=0;
                alpha_1 = deg2rad(90); alpha_2 = 0; alpha_3 = 0;
                a_1 = 0; a_2 = 5; a_3 = 3;
                th12 = theta_1+theta_2; th23 = theta_2+theta_3; th13 = theta_1+theta_2+theta_3;

                dt=0.1;
                t = [0+dt:dt:3];
                L = length(t);

                dy=33/103;
                YY = [-15+dy:dy:-5];
                Lz = length(YY);
                z2nd = -8/25*(-5+10)^2+10;
                z3nd = -8/25*(-10+10)^2+10;
                z2 = z2nd;
                z3 = z3nd;

                zp = zeros(1,(Lz-1)/2); % 저장용도
                for i=1:(Lz-1)/2
                    z2(i)= zp(i) + -8/25*(-5-dy*i+10)^2+10;
                    z3(i)= zp(i) + -8/25*(-10-dy*i+10)^2+10;
                end

                for i=2:(Lz-1)/2
                    dzt3(i) = (z2(i)-z2(i-1))/dt;
                    dzt4(i) = (z3(i)-z3(i-1))/dt;
                end

                dotpz2 = zeros(1,Lz-1);
                dotpz2(1:(Lz-1)/2) = dzt3;
                dotpz2((Lz-1)/2+1:Lz-1) = dzt4;

                for i=2:Lz-1
                    dotpy(i) = (YY(i)-YY(i-1))/dt;
                end

                dotpx = zeros(1,Lz-1);

                %%
                for i=2:L
                    J = [-sin(theta_1(i-1))*(a_2*cos(theta_2(i-1))+a_3*cos(th23(i-1))), -cos(theta_1(i-1))*(a_2*sin(theta_2(i-1))+a_3*sin(th23(i-1))), -a_3*cos(theta_1(i-1))*sin(th23(i-1));
                        cos(theta_1(i-1))*(a_2*cos(theta_2(i-1))+a_3*cos(th23(i-1))), -sin(theta_1(i-1))*(a_2*sin(theta_2(i-1))+a_3*sin(th23(i-1))), -a_3*sin(theta_1(i-1))*sin(th23(i-1));
                        0                                  , a_2*cos(theta_2(i-1))+a_3*cos(th23(i-1))            , a_3*cos(th23(i-1))];
                    dth(i,:) = inv(J)*[dotpx(i); dotpy(i); dotpz2(i)]; % 2차
                    dth1(i,1) = dth(i,1);
                    dth2(i,1) = dth(i,2);
                    dth3(i,1) = dth(i,3);
                    theta_1(i) = theta_1(i-1)+dth1(i,1)*dt;
                    theta_2(i) = theta_2(i-1)+dth2(i,1)*dt;
                    theta_3(i) = theta_3(i-1)+dth3(i,1)*dt;
                    theta(i,:) = [theta_1(i), theta_2(i), theta_3(i)];
                    th23(i) = theta_2(i)+theta_3(i);
                    %     g = 9.81;
                    %     Torque(i,:) = J'*[Fx(i); Fy(i); Fz(i)];
                    dP(i,:) = J * [dth1(i); dth2(i); dth3(i)];
                    dPx(i,1) = dP(i,1);
                    dPy(i,1) = dP(i,2);
                    dPz(i,1) = dP(i,3);

                    Dhs=[theta_1(i), d_1, alpha_1, a_1;
                        theta_2(i), d_2, alpha_2, a_2;
                        theta_3(i), d_3, alpha_3, a_3];

                    [Tc,Tl] = genTm(Dhs);
                    surf(app.UIAxes,X2,Y2,Z2);
                    hold(app.UIAxes,"on");
                    drawCord(Tc);
                    drawLink(Tc);
                    pause(0.5);
                    hold(app.UIAxes,"off");
                end
            end
            function drawLink(A)
                [l,c,w] = size(A);
                Color_1=app.link1_ColorDropDown.Value;
                Color_2=app.link2_ColorDropDown.Value;
                Color_3=app.link3_ColorDropDown.Value;

                if strcmp('Red',Color_1)
                    link1_C=[1 0 0];
                elseif strcmp('Blue',Color_1)
                    link1_C=[0 0 1];
                else
                    link1_C=[0 1 0];
                end
                if strcmp('Red',Color_2)
                    link2_C=[1 0 0];
                elseif strcmp('Blue',Color_2)
                    link2_C=[0 0 1];
                else
                    link2_C=[0 1 0];
                end
                if strcmp('Red',Color_3)
                    link3_C=[1 0 0];
                elseif strcmp('Blue',Color_3)
                    link3_C=[0 0 1];
                else
                    link3_C=[0 1 0];
                end

                Color=[link1_C;link2_C;link3_C];
                for i=1:1:w
                    if (i==1)
                        px=A(1,4,i);
                        py=A(2,4,i);
                        pz=A(3,4,i);
                        drawEdge3d(app.UIAxes,[0 0 0 px py pz],'Color',Color(i,:),'LineWidth',5);
                    else
                        x_0=A(1,4,i-1);
                        y_0=A(2,4,i-1);
                        z_0=A(3,4,i-1);
                        x_1=A(1,4,i);
                        y_1=A(2,4,i);
                        z_1=A(3,4,i);
                        drawEdge3d(app.UIAxes,[x_0 y_0 z_0 x_1 y_1 z_1],'Color',Color(i,:),'LineWidth',5);
                    end
                end
            end
            function drawCord(A)
                [l c w]=size(A);
                drawVector3d(app.UIAxes,[0,0,0],[5,0,0],'LineWidth',1,'Color',[0,0,0]);
                hold(app.UIAxes,'on');
                drawVector3d(app.UIAxes,[0,0,0],[0,5,0],'LineWidth',1,'Color',[0,0,0]);
                drawVector3d(app.UIAxes,[0,0,0],[0,0,5],'LineWidth',1,'Color',[0,0,0]);
                for i = 1:1:w
                    A_1 = A(:,:,i);
                    V_x = createVector([0,0,0],[A_1(1,1),A_1(2,1),A_1(3,1)]);
                    V_y = createVector([0,0,0],[A_1(1,2),A_1(2,2),A_1(3,2)]);
                    V_z = createVector([0,0,0],[A_1(1,3),A_1(2,3),A_1(3,3)]);
                    drawVector3d(app.UIAxes,[A_1(1,4),A_1(2,4),A_1(3,4)],V_x,'LineWidth',1.5,'Color',[1,0,0]);
                    drawVector3d(app.UIAxes,[A_1(1,4),A_1(2,4),A_1(3,4)],V_y,'LineWidth',1.5,'Color',[1,0,0]);
                    drawVector3d(app.UIAxes,[A_1(1,4),A_1(2,4),A_1(3,4)],V_z,'LineWidth',1.5,'Color',[1,0,0]);
                end
            end
            function T=DH(Dhs)
                th = Dhs(1);
                d = Dhs(2);
                alp = Dhs(3);
                a = Dhs(4);
                T=[ cos(th),  -sin(th)*cos(alp),  sin(th)*sin(alp), a*cos(th);...
                    sin(th),  cos(th)*cos(alp),   -cos(th)*sin(alp), a*sin(th);...
                    0,         sin(alp),            cos(alp),         d;...
                    0,            0,                   0,             1];
            end
            function [Tc,Tl] = genTm(Dhs)
                [l c] = size(Dhs);
                for i = 1:1:l
                    if (i==1)
                        Dhs(i,:)
                        Tl(:,:,i)=DH(Dhs(i,:));
                        Tc(:,:,i)=eye(4)*DH(Dhs(i,:));
                    else
                        Tl(:,:,i)=DH(Dhs(i,:));
                        Tc(:,:,i)=Tc(:,:,i-1)*Tl(:,:,i);
                    end
                end
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: dtheta_1EditField
        function dtheta_1EditFieldValueChanged(app, event)
            value = app.dtheta_1EditField.Value;
            
        end

        % Button pushed function: PointButton
        function PointButtonPushed(app, event)
            hold(app.UIAxes,'off');
            hold(app.UIAxes,"on");
            [X,Y,Z]=sphere;
            if app.DropDown.Value=='1번 궤도'
                r=1.5;
                X2=X*r;
                Y2=Y*r-3;
                Z2=Z*r+2;
                surf(app.UIAxes,X2,Y2,Z2);
                theta_1 = deg2rad(app.link1_thetaEditField.Value);
                theta_2 = deg2rad(app.link2_thetaEditField.Value);
                theta_3 = deg2rad(app.link3_thetaEditField.Value);
                d_1 = app.link1_dEditField.Value;
                d_2 = app.link2_dEditField.Value;
                d_3 = app.link3_dEditField.Value;
                alpha_1 = deg2rad(app.link1_alphaEditField.Value);
                alpha_2 = deg2rad(app.link2_alphaEditField.Value);
                alpha_3 = deg2rad(app.link3_alphaEditField.Value);
                a_1 = app.link1_aEditField.Value;
                a_2 = app.link2_aEditField.Value;
                a_3 = app.link3_aEditField.Value;
                th12 = theta_1+theta_2; th23 = theta_2+theta_3; th13 = theta_1+theta_2+theta_3;
                dt=0.1;
                t = [0+dt:dt:3];
                L = length(t);
                dy=33/103;
                YY = [-15+dy:dy:-5];
                Lz = length(YY);
                z = -12/5*(-5)-6;
                zz = 12/5*(-10)+26;
                z0 = z;
                z1 = zz;
                zp = zeros(1,(Lz-1)/2); % 저장용도
                for i=1:(Lz-1)/2
                    z0(i)= zp(i) + -12/5*(-5-dy*i)-6;
                    z1(i)= zp(i) + 12/5*(-10-dy*i)+26;
                end
                for i=2:(Lz-1)/2
                    dzt1(i) = (z0(i)-z0(i-1))/dt;
                    dzt2(i) = (z1(i)-z1(i-1))/dt;
                end
                dotpz = zeros(1,Lz-1);
                dotpz(1:(Lz-1)/2) = dzt1;
                dotpz((Lz-1)/2+1:Lz-1) = dzt2;
                for i=2:Lz-1
                    dotpy(i) = (YY(i)-YY(i-1))/dt;
                end
                dotpx = zeros(1,Lz-1);
                for j=2:L
                    J = [-sin(theta_1(j-1))*(a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))), -cos(theta_1(j-1))*(a_2*sin(theta_2(j-1))+a_3*sin(th23(j-1))), -a_3*cos(theta_1(j-1))*sin(th23(j-1));
                        cos(theta_1(j-1))*(a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))), -sin(theta_1(j-1))*(a_2*sin(theta_2(j-1))+a_3*sin(th23(j-1))), -a_3*sin(theta_1(j-1))*sin(th23(j-1));
                        0                                  , a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))            , a_3*cos(th23(j-1))];
                    dth(j,:) = inv(J)*[dotpx(j); dotpy(j); dotpz(j)];
                    dth1(j,1) = dth(j,1);
                    dth2(j,1) = dth(j,2);
                    dth3(j,1) = dth(j,3);
                    theta_1(j) = theta_1(j-1)+dth1(j,1)*dt;
                    theta_2(j) = theta_2(j-1)+dth2(j,1)*dt;
                    theta_3(j) = theta_3(j-1)+dth3(j,1)*dt;
                    theta(j,:) = [theta_1(j), theta_2(j), theta_3(j)];
                    th23(j) = theta_2(j)+theta_3(j);
                    Dhs=[theta_1(j), d_1, alpha_1, a_1;
                        theta_2(j), d_2, alpha_2, a_2;
                        theta_3(j), d_3, alpha_3, a_3];

                    [Tc,Tl] = genTm(Dhs);
                    hold(app.UIAxes,"on");
                    drawPoint3d(app.UIAxes,Tc(1,4,3),Tc(2,4,3),Tc(3,4,3),'Marker','*','color',[1 0 0],'MarkerSize',7);
                    pause(0.5);

                end
            elseif app.DropDown.Value=='2번 궤도'
                r=1;
                X2=X*r;
                Y2=Y*r-2;
                Z2=Z*r+2;
                surf(app.UIAxes,X2,Y2,Z2);
                % 초기값
                theta_1 = deg2rad(app.link1_thetaEditField.Value);
                theta_2 = deg2rad(app.link2_thetaEditField.Value);
                theta_3 = deg2rad(app.link3_thetaEditField.Value);
                d_1 = app.link1_dEditField.Value;
                d_2 = app.link2_dEditField.Value;
                d_3 = app.link3_dEditField.Value;
                alpha_1 = deg2rad(app.link1_alphaEditField.Value);
                alpha_2 = deg2rad(app.link2_alphaEditField.Value);
                alpha_3 = deg2rad(app.link3_alphaEditField.Value);
                a_1 = app.link1_aEditField.Value;
                a_2 = app.link2_aEditField.Value;
                a_3 = app.link3_aEditField.Value;
                th12 = theta_1+theta_2; th23 = theta_2+theta_3; th13 = theta_1+theta_2+theta_3;
                dt=0.1;
                t = [0+dt:dt:3];
                L = length(t);

                dy=33/103;
                YY = [-15+dy:dy:-5];
                Lz = length(YY);
                z2nd = -8/25*(-5+10)^2+10;
                z3nd = -8/25*(-10+10)^2+10;
                z2 = z2nd;
                z3 = z3nd;

                zp = zeros(1,(Lz-1)/2); % 저장용도
                for i=1:(Lz-1)/2
                    z2(i)= zp(i) + -8/25*(-5-dy*i+10)^2+10;
                    z3(i)= zp(i) + -8/25*(-10-dy*i+10)^2+10;
                end

                for i=2:(Lz-1)/2
                    dzt3(i) = (z2(i)-z2(i-1))/dt;
                    dzt4(i) = (z3(i)-z3(i-1))/dt;
                end

                dotpz2 = zeros(1,Lz-1);
                dotpz2(1:(Lz-1)/2) = dzt3;
                dotpz2((Lz-1)/2+1:Lz-1) = dzt4;

                for i=2:Lz-1
                    dotpy(i) = (YY(i)-YY(i-1))/dt;
                end

                dotpx = zeros(1,Lz-1);

                %%
                for i=2:L
                    J = [-sin(theta_1(i-1))*(a_2*cos(theta_2(i-1))+a_3*cos(th23(i-1))), -cos(theta_1(i-1))*(a_2*sin(theta_2(i-1))+a_3*sin(th23(i-1))), -a_3*cos(theta_1(i-1))*sin(th23(i-1));
                        cos(theta_1(i-1))*(a_2*cos(theta_2(i-1))+a_3*cos(th23(i-1))), -sin(theta_1(i-1))*(a_2*sin(theta_2(i-1))+a_3*sin(th23(i-1))), -a_3*sin(theta_1(i-1))*sin(th23(i-1));
                        0                                  , a_2*cos(theta_2(i-1))+a_3*cos(th23(i-1))            , a_3*cos(th23(i-1))];
                    dth(i,:) = inv(J)*[dotpx(i); dotpy(i); dotpz2(i)]; % 2차
                    dth1(i,1) = dth(i,1);
                    dth2(i,1) = dth(i,2);
                    dth3(i,1) = dth(i,3);
                    theta_1(i) = theta_1(i-1)+dth1(i,1)*dt;
                    theta_2(i) = theta_2(i-1)+dth2(i,1)*dt;
                    theta_3(i) = theta_3(i-1)+dth3(i,1)*dt;
                    theta(i,:) = [theta_1(i), theta_2(i), theta_3(i)];
                    th23(i) = theta_2(i)+theta_3(i);
                    %     g = 9.81;
                    %     Torque(i,:) = J'*[Fx(i); Fy(i); Fz(i)];
                    dP(i,:) = J * [dth1(i); dth2(i); dth3(i)];
                    dPx(i,1) = dP(i,1);
                    dPy(i,1) = dP(i,2);
                    dPz(i,1) = dP(i,3);

                    Dhs=[theta_1(i), d_1, alpha_1, a_1;
                        theta_2(i), d_2, alpha_2, a_2;
                        theta_3(i), d_3, alpha_3, a_3];

                    [Tc,Tl] = genTm(Dhs);
                    surf(app.UIAxes,X2,Y2,Z2);
                    hold(app.UIAxes,"on");
                    drawPoint3d(app.UIAxes,Tc(1,4,3),Tc(2,4,3),Tc(3,4,3),'Marker','*','color',[1 0 0],'MarkerSize',7);
                    pause(0.5);
                end
            end
            
            function T=DH(Dhs)
                th = Dhs(1);
                d = Dhs(2);
                alp = Dhs(3);
                a = Dhs(4);
                T=[ cos(th),  -sin(th)*cos(alp),  sin(th)*sin(alp), a*cos(th);...
                    sin(th),  cos(th)*cos(alp),   -cos(th)*sin(alp), a*sin(th);...
                    0,         sin(alp),            cos(alp),         d;...
                    0,            0,                   0,             1];
            end
            function [Tc,Tl] = genTm(Dhs)
                [l c] = size(Dhs);
                for i = 1:1:l
                    if (i==1)
                        Dhs(i,:)
                        Tl(:,:,i)=DH(Dhs(i,:));
                        Tc(:,:,i)=eye(4)*DH(Dhs(i,:));
                    else
                        Tl(:,:,i)=DH(Dhs(i,:));
                        Tc(:,:,i)=Tc(:,:,i-1)*Tl(:,:,i);
                    end
                end
            end
        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed(app, event)
            hold(app.UIAxes,"on");
            [X,Y,Z]=sphere;
            if app.DropDown.Value=='1번 궤도'
                r=1.5;
                X2=X*r;
                Y2=Y*r-3;
                Z2=Z*r+2;
                surf(app.UIAxes,X2,Y2,Z2);
                theta_1 = deg2rad(app.link1_thetaEditField.Value);
                theta_2 = deg2rad(app.link2_thetaEditField.Value);
                theta_3 = deg2rad(app.link3_thetaEditField.Value);
                d_1 = app.link1_dEditField.Value;
                d_2 = app.link2_dEditField.Value;
                d_3 = app.link3_dEditField.Value;
                alpha_1 = deg2rad(app.link1_alphaEditField.Value);
                alpha_2 = deg2rad(app.link2_alphaEditField.Value);
                alpha_3 = deg2rad(app.link3_alphaEditField.Value);
                a_1 = app.link1_aEditField.Value;
                a_2 = app.link2_aEditField.Value;
                a_3 = app.link3_aEditField.Value;
                th12 = theta_1+theta_2; th23 = theta_2+theta_3; th13 = theta_1+theta_2+theta_3;
                dt=0.1;
                t = [0+dt:dt:3];
                L = length(t);
                dy=33/103;
                YY = [-15+dy:dy:-5];
                Lz = length(YY);
                z = -12/5*(-5)-6;
                zz = 12/5*(-10)+26;
                z0 = z;
                z1 = zz;
                zp = zeros(1,(Lz-1)/2); % 저장용도
                for i=1:(Lz-1)/2
                    z0(i)= zp(i) + -12/5*(-5-dy*i)-6;
                    z1(i)= zp(i) + 12/5*(-10-dy*i)+26;
                end
                for i=2:(Lz-1)/2
                    dzt1(i) = (z0(i)-z0(i-1))/dt;
                    dzt2(i) = (z1(i)-z1(i-1))/dt;
                end
                dotpz = zeros(1,Lz-1);
                dotpz(1:(Lz-1)/2) = dzt1;
                dotpz((Lz-1)/2+1:Lz-1) = dzt2;
                for i=2:Lz-1
                    dotpy(i) = (YY(i)-YY(i-1))/dt;
                end
                dotpx = zeros(1,Lz-1);
                figure(1);
                subplot(1,3,1);
                plot(t,dotpx); title('dotPx');
                hold on;
                subplot(1,3,2);
                plot(t,dotpy); title('dotPy');
                subplot(1,3,3);
                plot(t,dotpz); title('dotPz');
            elseif app.DropDown.Value=='2번 궤도'
                r=1;
                X2=X*r;
                Y2=Y*r-2;
                Z2=Z*r+2;
                surf(app.UIAxes,X2,Y2,Z2);
                theta_1 = deg2rad(app.link1_thetaEditField.Value);
                theta_2 = deg2rad(app.link2_thetaEditField.Value);
                theta_3 = deg2rad(app.link3_thetaEditField.Value);
                d_1 = app.link1_dEditField.Value;
                d_2 = app.link2_dEditField.Value;
                d_3 = app.link3_dEditField.Value;
                alpha_1 = deg2rad(app.link1_alphaEditField.Value);
                alpha_2 = deg2rad(app.link2_alphaEditField.Value);
                alpha_3 = deg2rad(app.link3_alphaEditField.Value);
                a_1 = app.link1_aEditField.Value;
                a_2 = app.link2_aEditField.Value;
                a_3 = app.link3_aEditField.Value;
                th12 = theta_1+theta_2; th23 = theta_2+theta_3; th13 = theta_1+theta_2+theta_3;
                dt=0.1;
                t = [0+dt:dt:3];
                L = length(t);

                dy=33/103;
                YY = [-15+dy:dy:-5];
                Lz = length(YY);
                z2nd = -8/25*(-5+10)^2+10;
                z3nd = -8/25*(-10+10)^2+10;
                z2 = z2nd;
                z3 = z3nd;

                zp = zeros(1,(Lz-1)/2); % 저장용도
                for i=1:(Lz-1)/2
                    z2(i)= zp(i) + -8/25*(-5-dy*i+10)^2+10;
                    z3(i)= zp(i) + -8/25*(-10-dy*i+10)^2+10;
                end

                for i=2:(Lz-1)/2
                    dzt3(i) = (z2(i)-z2(i-1))/dt;
                    dzt4(i) = (z3(i)-z3(i-1))/dt;
                end

                dotpz2 = zeros(1,Lz-1);
                dotpz2(1:(Lz-1)/2) = dzt3;
                dotpz2((Lz-1)/2+1:Lz-1) = dzt4;

                for i=2:Lz-1
                    dotpy(i) = (YY(i)-YY(i-1))/dt;
                end

                dotpx = zeros(1,Lz-1);
                figure(1);
                subplot(1,3,1);
                plot(t,dotpx); title('dotPx');
                hold on;
                subplot(1,3,2);
                plot(t,dotpy); title('dotPy');
                subplot(1,3,3);
                plot(t,dotpz2); title('dotPz');
            end
        end

        % Button pushed function: TorqueButton
        function TorqueButtonPushed(app, event)
            if app.DropDown.Value=='1번 궤도'
                theta_1 = deg2rad(app.link1_thetaEditField.Value);
                theta_2 = deg2rad(app.link2_thetaEditField.Value);
                theta_3 = deg2rad(app.link3_thetaEditField.Value);
                d_1 = app.link1_dEditField.Value;
                d_2 = app.link2_dEditField.Value;
                d_3 = app.link3_dEditField.Value;
                alpha_1 = deg2rad(app.link1_alphaEditField.Value);
                alpha_2 = deg2rad(app.link2_alphaEditField.Value);
                alpha_3 = deg2rad(app.link3_alphaEditField.Value);
                a_1 = app.link1_aEditField.Value;
                a_2 = app.link2_aEditField.Value;
                a_3 = app.link3_aEditField.Value;
                th12 = theta_1+theta_2; th23 = theta_2+theta_3; th13 = theta_1+theta_2+theta_3;
                dt=0.1;
                t = [0+dt:dt:3];
                L = length(t);
                dy=33/103;
                YY = [-15+dy:dy:-5];
                Lz = length(YY);
                z = -12/5*(-5)-6;
                zz = 12/5*(-10)+26;
                z0 = z;
                z1 = zz;
                zp = zeros(1,(Lz-1)/2); % 저장용도
                for i=1:(Lz-1)/2
                    z0(i)= zp(i) + -12/5*(-5-dy*i)-6;
                    z1(i)= zp(i) + 12/5*(-10-dy*i)+26;
                end
                for i=2:(Lz-1)/2
                    dzt1(i) = (z0(i)-z0(i-1))/dt;
                    dzt2(i) = (z1(i)-z1(i-1))/dt;
                end
                dotpz = zeros(1,Lz-1);
                dotpz(1:(Lz-1)/2) = dzt1;
                dotpz((Lz-1)/2+1:Lz-1) = dzt2;
                for i=2:Lz-1
                    dotpy(i) = (YY(i)-YY(i-1))/dt;
                end
                dotpx = zeros(1,Lz-1);
                for j=2:L
                    J = [-sin(theta_1(j-1))*(a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))), -cos(theta_1(j-1))*(a_2*sin(theta_2(j-1))+a_3*sin(th23(j-1))), -a_3*cos(theta_1(j-1))*sin(th23(j-1));
                        cos(theta_1(j-1))*(a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))), -sin(theta_1(j-1))*(a_2*sin(theta_2(j-1))+a_3*sin(th23(j-1))), -a_3*sin(theta_1(j-1))*sin(th23(j-1));
                        0                                  , a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))            , a_3*cos(th23(j-1))];
                    dth(j,:) = inv(J)*[dotpx(j); dotpy(j); dotpz(j)];
                    dth1(j,1) = dth(j,1);
                    dth2(j,1) = dth(j,2);
                    dth3(j,1) = dth(j,3);
                    theta_1(j) = theta_1(j-1)+dth1(j,1)*dt;
                    theta_2(j) = theta_2(j-1)+dth2(j,1)*dt;
                    theta_3(j) = theta_3(j-1)+dth3(j,1)*dt;
                    theta(j,:) = [theta_1(j), theta_2(j), theta_3(j)];
                    th23(j) = theta_2(j)+theta_3(j);
                    Dhs=[theta_1(j), d_1, alpha_1, a_1;
                        theta_2(j), d_2, alpha_2, a_2;
                        theta_3(j), d_3, alpha_3, a_3];
                    F=[0;0;-5*9.81];
                    torque1(j)=J(1,:)*F;
                    torque2(j)=J(2,:)*F;
                    torque3(j)=J(3,:)*F;
                end
                figure(1);
                subplot(1,3,1);
                plot(t,torque1); title("Torque1");
                subplot(1,3,2);
                plot(t,torque2); title("Torque2");
                subplot(1,3,3);
                plot(t,torque3); title("Torque3");
            elseif app.DropDown.Value=='2번 궤도'


                % 초기값
                theta_1 = deg2rad(-90); theta_2 = 0; theta_3 = deg2rad(-90);
                d_1 = 5; d_2 =0; d_3=0;
                alpha_1 = deg2rad(90); alpha_2 = 0; alpha_3 = 0;
                a_1 = 0; a_2 = 5; a_3 = 3;
                th12 = theta_1+theta_2; th23 = theta_2+theta_3; th13 = theta_1+theta_2+theta_3;

                dt=0.1;
                t = [0+dt:dt:3];
                L = length(t);

                dy=33/103;
                YY = [-15+dy:dy:-5];
                Lz = length(YY);
                z2nd = -8/25*(-5+10)^2+10;
                z3nd = -8/25*(-10+10)^2+10;
                z2 = z2nd;
                z3 = z3nd;

                zp = zeros(1,(Lz-1)/2); % 저장용도
                for i=1:(Lz-1)/2
                    z2(i)= zp(i) + -8/25*(-5-dy*i+10)^2+10;
                    z3(i)= zp(i) + -8/25*(-10-dy*i+10)^2+10;
                end

                for i=2:(Lz-1)/2
                    dzt3(i) = (z2(i)-z2(i-1))/dt;
                    dzt4(i) = (z3(i)-z3(i-1))/dt;
                end

                dotpz2 = zeros(1,Lz-1);
                dotpz2(1:(Lz-1)/2) = dzt3;
                dotpz2((Lz-1)/2+1:Lz-1) = dzt4;

                for i=2:Lz-1
                    dotpy(i) = (YY(i)-YY(i-1))/dt;
                end

                dotpx = zeros(1,Lz-1);

                %%
                for i=2:L
                    J = [-sin(theta_1(i-1))*(a_2*cos(theta_2(i-1))+a_3*cos(th23(i-1))), -cos(theta_1(i-1))*(a_2*sin(theta_2(i-1))+a_3*sin(th23(i-1))), -a_3*cos(theta_1(i-1))*sin(th23(i-1));
                        cos(theta_1(i-1))*(a_2*cos(theta_2(i-1))+a_3*cos(th23(i-1))), -sin(theta_1(i-1))*(a_2*sin(theta_2(i-1))+a_3*sin(th23(i-1))), -a_3*sin(theta_1(i-1))*sin(th23(i-1));
                        0                                  , a_2*cos(theta_2(i-1))+a_3*cos(th23(i-1))            , a_3*cos(th23(i-1))];
                    dth(i,:) = inv(J)*[dotpx(i); dotpy(i); dotpz2(i)]; % 2차
                    dth1(i,1) = dth(i,1);
                    dth2(i,1) = dth(i,2);
                    dth3(i,1) = dth(i,3);
                    theta_1(i) = theta_1(i-1)+dth1(i,1)*dt;
                    theta_2(i) = theta_2(i-1)+dth2(i,1)*dt;
                    theta_3(i) = theta_3(i-1)+dth3(i,1)*dt;
                    theta(i,:) = [theta_1(i), theta_2(i), theta_3(i)];
                    th23(i) = theta_2(i)+theta_3(i);
                    dP(i,:) = J * [dth1(i); dth2(i); dth3(i)];
                    dPx(i,1) = dP(i,1);
                    dPy(i,1) = dP(i,2);
                    dPz(i,1) = dP(i,3);

                    Dhs=[theta_1(i), d_1, alpha_1, a_1;
                        theta_2(i), d_2, alpha_2, a_2;
                        theta_3(i), d_3, alpha_3, a_3];

                    F=[0;0;-5*9.81];
                    torque1(i)=J(1,:)*F;
                    torque2(i)=J(2,:)*F;
                    torque3(i)=J(3,:)*F;
                end
                figure(1);
                subplot(1,3,1);
                plot(t,torque1); title("Torque1");
                subplot(1,3,2);
                plot(t,torque2); title("Torque2");
                subplot(1,3,3);
                plot(t,torque3); title("Torque3");
            end
            
        end

        % Button pushed function: DHParameterButton
        function DHParameterButtonPushed(app, event)
            Dhs=0; %%local variable

            theta_1 = deg2rad(app.link1_thetaEditField.Value);
            d_1 = app.link1_dEditField.Value;
            alpha_1 = deg2rad(app.link1_alphaEditField.Value);
            a_1 = app.link1_aEditField.Value;

            theta_2 = deg2rad(app.link2_thetaEditField.Value);
            d_2 = app.link2_dEditField.Value;
            alpha_2 = deg2rad(app.link2_alphaEditField.Value);
            a_2 = app.link2_aEditField.Value;

            theta_3 = deg2rad(app.link3_thetaEditField.Value);
            d_3 = app.link3_dEditField.Value;
            alpha_3 = deg2rad(app.link3_alphaEditField.Value);
            a_3 = app.link3_aEditField.Value;

            Dhs=[theta_1 d_1 alpha_1 a_1;
                theta_2 d_2 alpha_2 a_2;
                theta_3 d_3 alpha_3 a_3];
            n = [1;2;3];
            tdata = table(n,Dhs,'VariableNames',{'Link Num','DH pharmeter'})
            fig = uifigure;
            uit = uitable(fig,'Data',tdata);
        end

        % Button pushed function: JacobianButton
        function JacobianButtonPushed(app, event)
            theta_1 = deg2rad(app.link1_thetaEditField.Value);
                theta_2 = deg2rad(app.link2_thetaEditField.Value);
                theta_3 = deg2rad(app.link3_thetaEditField.Value);
                d_1 = app.link1_dEditField.Value;
                d_2 = app.link2_dEditField.Value;
                d_3 = app.link3_dEditField.Value;
                alpha_1 = deg2rad(app.link1_alphaEditField.Value);
                alpha_2 = deg2rad(app.link2_alphaEditField.Value);
                alpha_3 = deg2rad(app.link3_alphaEditField.Value);
                a_1 = app.link1_aEditField.Value;
                a_2 = app.link2_aEditField.Value;
                a_3 = app.link3_aEditField.Value;
                th12 = theta_1+theta_2; th23 = theta_2+theta_3; th13 = theta_1+theta_2+theta_3;
                dt=0.1;
                t = [0+dt:dt:3];
                L = length(t);
                                dy=33/103;
                YY = [-15+dy:dy:-5];
                Lz = length(YY);
                z = -12/5*(-5)-6;
                zz = 12/5*(-10)+26;
                z0 = z;
                z1 = zz;
                zp = zeros(1,(Lz-1)/2); % 저장용도
                for i=1:(Lz-1)/2
                    z0(i)= zp(i) + -12/5*(-5-dy*i)-6;
                    z1(i)= zp(i) + 12/5*(-10-dy*i)+26;
                end
                for i=2:(Lz-1)/2
                    dzt1(i) = (z0(i)-z0(i-1))/dt;
                    dzt2(i) = (z1(i)-z1(i-1))/dt;
                end
                dotpz = zeros(1,Lz-1);
                dotpz(1:(Lz-1)/2) = dzt1;
                dotpz((Lz-1)/2+1:Lz-1) = dzt2;
                for i=2:Lz-1
                    dotpy(i) = (YY(i)-YY(i-1))/dt;
                end
                dotpx = zeros(1,Lz-1);
                for j=2:L
                    J = [-sin(theta_1(j-1))*(a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))), -cos(theta_1(j-1))*(a_2*sin(theta_2(j-1))+a_3*sin(th23(j-1))), -a_3*cos(theta_1(j-1))*sin(th23(j-1));
                        cos(theta_1(j-1))*(a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))), -sin(theta_1(j-1))*(a_2*sin(theta_2(j-1))+a_3*sin(th23(j-1))), -a_3*sin(theta_1(j-1))*sin(th23(j-1));
                        0                                  , a_2*cos(theta_2(j-1))+a_3*cos(th23(j-1))            , a_3*cos(th23(j-1))];
                    dth(j,:) = inv(J)*[dotpx(j); dotpy(j); dotpz(j)];
                    dth1(j,1) = dth(j,1);
                    dth2(j,1) = dth(j,2);
                    dth3(j,1) = dth(j,3);
                    theta_1(j) = theta_1(j-1)+dth1(j,1)*dt;
                    theta_2(j) = theta_2(j-1)+dth2(j,1)*dt;
                    theta_3(j) = theta_3(j-1)+dth3(j,1)*dt;
                    theta(j,:) = [theta_1(j), theta_2(j), theta_3(j)];
                    th23(j) = theta_2(j)+theta_3(j);
                end
                n = [1;2;3];
                tdata = table(n,J,'VariableNames',{'XYZ','Jacobian'});
                fig = uifigure;
                uit = uitable(fig,'Data',tdata);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-16 16];
            app.UIAxes.YLim = [-16 16];
            app.UIAxes.ZLim = [-16 16];
            app.UIAxes.XTick = [-16 -14 -12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12 14 16];
            app.UIAxes.XTickLabel = {'-16'; '-14'; '-12'; '-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'};
            app.UIAxes.YTick = [-16 -14 -12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12 14 16];
            app.UIAxes.YTickLabel = {'-16'; '-14'; '-12'; '-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'};
            app.UIAxes.ZTick = [-16 -14 -12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12 14 16];
            app.UIAxes.ZTickLabel = {'-16'; '-14'; '-12'; '-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.link1_ColorDropDownLabel = uilabel(app.UIFigure);
            app.link1_ColorDropDownLabel.Text = 'link1_Color';
            app.link1_ColorDropDown = uidropdown(app.UIFigure);
            app.link1_ColorDropDown.Items = {'Red', 'Blue', 'Green'};
            app.link1_ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @link1_ColorDropDownValueChanged, true);
            app.link1_ColorDropDown.Value = 'Red';
            app.link1_aEditFieldLabel = uilabel(app.UIFigure);
            app.link1_aEditFieldLabel.Text = 'link1_a';
            app.link1_aEditField = uieditfield(app.UIFigure, 'numeric');
            app.link1_aEditField.ValueChangedFcn = createCallbackFcn(app, @link1_aEditFieldValueChanged, true);
            app.link1_dEditFieldLabel = uilabel(app.UIFigure);
            app.link1_dEditFieldLabel.Text = 'link1_d';
            app.link1_dEditField = uieditfield(app.UIFigure, 'numeric');
            app.link1_dEditField.ValueChangedFcn = createCallbackFcn(app, @link1_dEditFieldValueChanged, true);
            app.link1_alphaEditFieldLabel = uilabel(app.UIFigure);
            app.link1_alphaEditFieldLabel.Text = 'link1_alpha';
            app.link1_alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.link1_alphaEditField.ValueChangedFcn = createCallbackFcn(app, @link1_alphaEditFieldValueChanged, true);
            app.link2_ColorDropDownLabel = uilabel(app.UIFigure);
            app.link2_ColorDropDownLabel.Text = 'link2_Color';
            app.link2_ColorDropDown = uidropdown(app.UIFigure);
            app.link2_ColorDropDown.Items = {'Red', 'Blue', 'Green'};
            app.link2_ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @link2_ColorDropDownValueChanged, true);
            app.link2_ColorDropDown.Value = 'Red';
            app.link2_aEditFieldLabel = uilabel(app.UIFigure);
            app.link2_aEditFieldLabel.Text = 'link2_a';
            app.link2_aEditField = uieditfield(app.UIFigure, 'numeric');
            app.link2_aEditField.ValueChangedFcn = createCallbackFcn(app, @link2_aEditFieldValueChanged, true);
            app.link2_thetaEditFieldLabel = uilabel(app.UIFigure);
            app.link2_thetaEditFieldLabel.Text = 'link2_theta';
            app.link2_thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.link2_thetaEditField.ValueChangedFcn = createCallbackFcn(app, @link2_thetaEditFieldValueChanged, true);
            app.link2_dEditFieldLabel = uilabel(app.UIFigure);
            app.link2_dEditFieldLabel.Text = 'link2_d';
            app.link2_dEditField = uieditfield(app.UIFigure, 'numeric');
            app.link2_dEditField.ValueChangedFcn = createCallbackFcn(app, @link2_dEditFieldValueChanged, true);
            app.link2_alphaEditFieldLabel = uilabel(app.UIFigure);
            app.link2_alphaEditFieldLabel.Text = 'link2_alpha';
            app.link2_alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.link2_alphaEditField.ValueChangedFcn = createCallbackFcn(app, @link2_alphaEditFieldValueChanged, true);
            app.link3_ColorDropDownLabel = uilabel(app.UIFigure);
            app.link3_ColorDropDownLabel.Text = 'link3_Color';
            app.link3_ColorDropDown = uidropdown(app.UIFigure);
            app.link3_ColorDropDown.Items = {'Red', 'Blue', 'Green'};
            app.link3_ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @link3_ColorDropDownValueChanged, true);
            app.link3_ColorDropDown.Value = 'Red';
            app.link3_aEditFieldLabel = uilabel(app.UIFigure);
            app.link3_aEditFieldLabel.Text = 'link3_a';
            app.link3_aEditField = uieditfield(app.UIFigure, 'numeric');
            app.link3_aEditField.ValueChangedFcn = createCallbackFcn(app, @link3_aEditFieldValueChanged, true);
            app.link3_thetaEditFieldLabel = uilabel(app.UIFigure);
            app.link3_thetaEditFieldLabel.Text = 'link3_theta';
            app.link3_thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.link3_thetaEditField.ValueChangedFcn = createCallbackFcn(app, @link3_thetaEditFieldValueChanged, true);
            app.link3_dEditFieldLabel = uilabel(app.UIFigure);
            app.link3_dEditFieldLabel.Text = 'link3_d';
            app.link3_dEditField = uieditfield(app.UIFigure, 'numeric');
            app.link3_dEditField.ValueChangedFcn = createCallbackFcn(app, @link3_dEditFieldValueChanged, true);
            app.link3_alphaEditFieldLabel = uilabel(app.UIFigure);
            app.link3_alphaEditFieldLabel.Text = 'link3_alpha';
            app.link3_alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.link3_alphaEditField.ValueChangedFcn = createCallbackFcn(app, @link3_alphaEditFieldValueChanged, true);
            app.link1_thetaEditFieldLabel = uilabel(app.UIFigure);
            app.link1_thetaEditFieldLabel.Text = 'link1_theta';
            app.link1_thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.link1_thetaEditField.ValueChangedFcn = createCallbackFcn(app, @link1_thetaEditFieldValueChanged, true);
            app.robot_armButton = uibutton(app.UIFigure, 'push');
            app.robot_armButton.ButtonPushedFcn = createCallbackFcn(app, @robot_armButtonPushed, true);
            app.robot_armButton.Text = 'robot_arm';
            app.show_pathButton = uibutton(app.UIFigure, 'push');
            app.show_pathButton.ButtonPushedFcn = createCallbackFcn(app, @show_pathButtonPushed, true);
            app.show_pathButton.Text = 'show_path';
            app.point_pathButton = uibutton(app.UIFigure, 'push');
            app.point_pathButton.ButtonPushedFcn = createCallbackFcn(app, @point_pathButtonPushed, true);
            app.point_pathButton.Text = 'point_path';
            app.SphereButton = uibutton(app.UIFigure, 'push');
            app.SphereButton.ButtonPushedFcn = createCallbackFcn(app, @SphereButtonPushed, true);
            app.SphereButton.Text = 'Sphere';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '장애물 종류';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'1번 궤도', '2번 궤도'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1번 궤도';
            app.dtheta_1EditFieldLabel = uilabel(app.UIFigure);
            app.dtheta_1EditFieldLabel.Text = 'dtheta_1';
            app.dtheta_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.dtheta_1EditField.ValueChangedFcn = createCallbackFcn(app, @dtheta_1EditFieldValueChanged, true);
            app.dtheta_2EditFieldLabel = uilabel(app.UIFigure);
            app.dtheta_2EditFieldLabel.Text = 'dtheta_2';
            app.dtheta_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.dtheta_3EditFieldLabel = uilabel(app.UIFigure);
            app.dtheta_3EditFieldLabel.Text = 'dtheta_3';
            app.dtheta_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'T';
            app.TSlider = uislider(app.UIFigure);
            app.TSlider.Limits = [0 3];
            app.PointButton = uibutton(app.UIFigure, 'push');
            app.PointButton.ButtonPushedFcn = createCallbackFcn(app, @PointButtonPushed, true);
            app.PointButton.Text = 'Point';
            app.VelocityButton = uibutton(app.UIFigure, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed, true);
            app.VelocityButton.Text = 'Velocity';
            app.TorqueButton = uibutton(app.UIFigure, 'push');
            app.TorqueButton.ButtonPushedFcn = createCallbackFcn(app, @TorqueButtonPushed, true);
            app.TorqueButton.Text = 'Torque';
            app.DHParameterButton = uibutton(app.UIFigure, 'push');
            app.DHParameterButton.ButtonPushedFcn = createCallbackFcn(app, @DHParameterButtonPushed, true);
            app.DHParameterButton.Text = 'D-H Parameter';
            app.JacobianButton = uibutton(app.UIFigure, 'push');
            app.JacobianButton.ButtonPushedFcn = createCallbackFcn(app, @JacobianButtonPushed, true);
            app.JacobianButton.Text = 'Jacobian';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_team

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end