classdef team_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResetButton                matlab.ui.control.Button
        SimulateButton             matlab.ui.control.Button
        Link3_dEditField           matlab.ui.control.NumericEditField
        Link3_dEditFieldLabel      matlab.ui.control.Label
        Link3_thetaEditField       matlab.ui.control.NumericEditField
        Link3_thetaEditFieldLabel  matlab.ui.control.Label
        Link2_dEditField           matlab.ui.control.NumericEditField
        Link2_dEditFieldLabel      matlab.ui.control.Label
        Link2_thetaEditField       matlab.ui.control.NumericEditField
        Link2_thetaEditFieldLabel  matlab.ui.control.Label
        Link1_dEditField           matlab.ui.control.NumericEditField
        Link1_dEditFieldLabel      matlab.ui.control.Label
        Link1_thetaEditField       matlab.ui.control.NumericEditField
        Link1_thetaEditFieldLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            plot(app.UIAxes,0,0);
            theta_1=deg2rad(app.Link1_thetaEditField.Value);
            d_1=app.Link1_dEditField.Value;
            theta_2=deg2rad(app.Link2_thetaEditField.Value);
            d_2=app.Link2_dEditField.Value;
            theta_3=deg2rad(app.Link3_thetaEditField.Value);
            d_3=app.Link3_dEditField.Value;
            x1=d_1*cos(theta_1);    y1=d_1*sin(theta_1);
            x2=x1+d_2*cos(theta_1+theta_2);    y2=y1+d_2*sin(theta_1+theta_2);
            x3=x2+d_3*cos(theta_1+theta_2+theta_3);    y3=y2+d_3*sin(theta_1+theta_2+theta_3);
            drawEdge(app.UIAxes,[0 0 x1 y1],'color','red','LineWidth',5);
            drawEdge(app.UIAxes,[x1 y1 x2 y2],'color','green','LineWidth',5);
            drawEdge(app.UIAxes,[x2 y2 x3 y3],'color','blue','LineWidth',5);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            plot(app.UIAxes,0,0);
            drawEdge(app.UIAxes,[0 0 2 0],'color','red','LineWidth',5);
            drawEdge(app.UIAxes,[2 0 4 0],'color','green','LineWidth',5);
            drawEdge(app.UIAxes,[4 0 6 0],'color','blue','LineWidth',5);
        end

        % Value changed function: Link1_thetaEditField
        function Link1_thetaEditFieldValueChanged(app, event)
            theta_1 = app.Link1_thetaEditField.Value;
        end

        % Value changed function: Link1_dEditField
        function Link1_dEditFieldValueChanged(app, event)
            d_1= app.Link1_dEditField.Value;
        end

        % Value changed function: Link2_thetaEditField
        function Link2_thetaEditFieldValueChanged(app, event)
            theta_2 = app.Link2_thetaEditField.Value;
        end

        % Value changed function: Link2_dEditField
        function Link2_dEditFieldValueChanged(app, event)
            d_2 = app.Link2_dEditField.Value;
        end

        % Value changed function: Link3_thetaEditField
        function Link3_thetaEditFieldValueChanged(app, event)
            theta_3 = app.Link3_thetaEditField.Value;
        end

        % Value changed function: Link3_dEditField
        function Link3_dEditFieldValueChanged(app, event)
            d_3 = app.Link3_dEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.XTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.UIAxes.XTickLabel = {'-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'};
            app.UIAxes.YTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.UIAxes.YTickLabel = {'-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'};
            app.UIAxes.ZTick = [0 0.5 1];
            app.Link1_thetaEditFieldLabel = uilabel(app.UIFigure);
            app.Link1_thetaEditFieldLabel.Text = 'Link1_theta';
            app.Link1_thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Link1_thetaEditField.ValueChangedFcn = createCallbackFcn(app, @Link1_thetaEditFieldValueChanged, true);
            app.Link1_dEditFieldLabel = uilabel(app.UIFigure);
            app.Link1_dEditFieldLabel.Text = 'Link1_d';
            app.Link1_dEditField = uieditfield(app.UIFigure, 'numeric');
            app.Link1_dEditField.ValueChangedFcn = createCallbackFcn(app, @Link1_dEditFieldValueChanged, true);
            app.Link2_thetaEditFieldLabel = uilabel(app.UIFigure);
            app.Link2_thetaEditFieldLabel.Text = 'Link2_theta';
            app.Link2_thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Link2_thetaEditField.ValueChangedFcn = createCallbackFcn(app, @Link2_thetaEditFieldValueChanged, true);
            app.Link2_dEditFieldLabel = uilabel(app.UIFigure);
            app.Link2_dEditFieldLabel.Text = 'Link2_d';
            app.Link2_dEditField = uieditfield(app.UIFigure, 'numeric');
            app.Link2_dEditField.ValueChangedFcn = createCallbackFcn(app, @Link2_dEditFieldValueChanged, true);
            app.Link3_thetaEditFieldLabel = uilabel(app.UIFigure);
            app.Link3_thetaEditFieldLabel.Text = 'Link3_theta';
            app.Link3_thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Link3_thetaEditField.ValueChangedFcn = createCallbackFcn(app, @Link3_thetaEditFieldValueChanged, true);
            app.Link3_dEditFieldLabel = uilabel(app.UIFigure);
            app.Link3_dEditFieldLabel.Text = 'Link3_d';
            app.Link3_dEditField = uieditfield(app.UIFigure, 'numeric');
            app.Link3_dEditField.ValueChangedFcn = createCallbackFcn(app, @Link3_dEditFieldValueChanged, true);
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = team_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end